classdef VisualisationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveClosePanel                 matlab.ui.container.Panel
        SaveAllFiguresButton           matlab.ui.control.Button
        SaveFileEditField              matlab.ui.control.EditField
        SaveFileLabel                  matlab.ui.control.Label
        CloseallButton                 matlab.ui.control.Button
        PlotSessionDataPanel           matlab.ui.container.Panel
        EnvelopeSwitch                 matlab.ui.control.Switch
        EnvelopeSwitchLabel            matlab.ui.control.Label
        OverlapCheckBox                matlab.ui.control.CheckBox
        LinkedAxesPanel                matlab.ui.container.Panel
        Tree3                          matlab.ui.container.CheckBoxTree
        CompareCheckBox                matlab.ui.control.CheckBox
        StackCheckBox                  matlab.ui.control.CheckBox
        Tree                           matlab.ui.container.CheckBoxTree
        PlotButton                     matlab.ui.control.Button
        GaitSwitch                     matlab.ui.control.Switch
        GaitSwitchLabel                matlab.ui.control.Label
        StimSwitch                     matlab.ui.control.Switch
        StimSwitchLabel                matlab.ui.control.Label
        PlotKinematicParametersPanel   matlab.ui.container.Panel
        CompareCheckBox_2              matlab.ui.control.CheckBox
        PlotButton_2                   matlab.ui.control.Button
        Tree2                          matlab.ui.container.CheckBoxTree
        PlotStimulationProtocolsPanel  matlab.ui.container.Panel
        BroadcastCheckBox              matlab.ui.control.CheckBox
        PlotButton_3                   matlab.ui.control.Button
        StimulationsListBox            matlab.ui.control.ListBox
        StimulationsListBoxLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        stim_tbl % stimulation params table
        GDP_data_dict % Dictionnary holding information on the session
        stim % Stim Flag: 1 = plot stim. Modified by Stim Toggle
        gait % Gait Flag: 1 = plot gait cycle. Modified by Gait Toggle
        envelope % Envelope Flag: 1 = plot envelope. Modified by Envelope Toggle
        stack % Stack Flag: 1 = plot should be plotted in one column
        compare_session % Comparison Flag: 1 = plot should be plotted across recordings
        basic_tree % Checkbox Tree used to refresh the Linked Axes Tree
        normalize % Normalization Flag: 1 = signals should be normalized time-wise when compared (not actually used in practice)
        compared_recs % Registry saving all currently compared recordings
        overlap % Overlap Flag: 1 = signals should be overlapped
        Broadcast % Broadcast Flag: 1 = Currently chosen stimulation protocols should be plotted on matching signals
    end
    
    methods (Access = private)
        
        %%
        %Initialize the Data Checkbox Tree for Session Data plotting
        function new_Tree = create_Tree(app,base_Tree)
            GDP_keys = keys(app.GDP_data_dict);
            rec_idx = find(contains(GDP_keys,'Rec'));
            N_rec = length(rec_idx);
            for idx=1:N_rec
                current_rec = app.GDP_data_dict(GDP_keys{rec_idx(idx)});
                rec_node = uitreenode(base_Tree,"Text",['Rec' num2str(idx)]);
                for i=1:length(current_rec)
                    acquisition_type = current_rec(i).acquisition_type;
                    rec_data = current_rec(i);
                    type_node = uitreenode(rec_node,"Text",acquisition_type,"NodeData",rec_data);
                    
                end
            end
            new_Tree = base_Tree;
        end
        
        %Initialize the Kinematic Parameters Plotting Checkbox Tree
        function new_Tree = create_Tree_2(app,base_Tree)
            GDP_keys = keys(app.GDP_data_dict);
            rec_idx = find(contains(GDP_keys,'Rec'));
            N_rec = length(rec_idx);
            all_node = uitreenode(base_Tree,"Text",'All Recs');
            for idx=1:N_rec
                current_rec = app.GDP_data_dict(GDP_keys{rec_idx(idx)});
                rec_node = uitreenode(all_node,"Text",['Rec' num2str(idx)]);
            end
            new_Tree = base_Tree;
        end
        
        %Updates the Linked Axes Checkbox Tree
        function new_Tree = create_Tree_Axes(app)
            %All axes can be linked if compare_session is True
            if app.compare_session
                
                base_Tree = app.basic_tree;
                all_node = uitreenode(base_Tree,"Text",'All Axes');
                fig_axes = get(gcf,'Children');
                fig_axes = flip(fig_axes);
                
                for idx=1:length(fig_axes)
                    if isa(fig_axes(idx),'matlab.graphics.axis.Axes')
                        axe_node = uitreenode(all_node,"Text",fig_axes(idx).YLabel.String,"NodeData",fig_axes(idx));
                    end
                end

                new_Tree = base_Tree;

            else
            %Axes can only be linked recording wise otherwise
                
                base_Tree = app.basic_tree;
                tree_children = base_Tree.Children;
                current_children = [];
                
                if ~isempty(tree_children)
                    for child_idx=1:length(tree_children)
                        current_children = [current_children tree_children(child_idx).Text];
                    end
                end
                
                GDP_keys = keys(app.GDP_data_dict);
                rec_idx = find(contains(GDP_keys,'Rec'));
                N_rec = length(rec_idx);
                fig_axes = get(gcf,'Children');
                fig_axes = flip(fig_axes);
                plot_title = fig_axes(end).String;
                parsed_title = split(plot_title,'-');
                %Recording Wise processing
                if ~isempty(current_children)
                    if any(strcmp(fig_axes(end).String,current_children))
                        rec_node = tree_children(find(strcmp(parsed_title{1},current_children)));
                    else
                        rec_node = uitreenode(base_Tree,"Text",parsed_title{1});
                    end
                else
                    rec_node = uitreenode(base_Tree,"Text",parsed_title{1});
                end
                
                acquisition_type = split(parsed_title{2},' ');
                acq_node = uitreenode(rec_node,"Text",acquisition_type{1});
                %Further acquisition type wise processing
                for idx=1:length(fig_axes)
                    if isa(fig_axes(idx),'matlab.graphics.axis.Axes')
                    node_data.axis = fig_axes(idx);
                    node_data.type = acquisition_type{1};
                    current_rec = parsed_title{1};
                    node_data.Rec = current_rec;
                    axe_node = uitreenode(acq_node,"Text",fig_axes(idx).YLabel.String,"NodeData",node_data);
                    end
                end
                new_Tree = base_Tree;

            end
        end
        
        %Convert the Data Checkbox Tree for Session Data plotting into a
        %struct for plotting
        function result = tree2struct(app)
            nodes = app.Tree.CheckedNodes;
            result = struct();
            for idx=1:length(nodes)
                node = nodes(idx);
                if ~isempty(node.NodeData)
                    NodeData = node.NodeData;
                    result.(NodeData.Rec).(NodeData.type).(node.Text) = NodeData.data;
                    result.(NodeData.Rec).(NodeData.type).unit = NodeData.unit;
                end
            end
        end
        
        %Convert the Linked Axes Tree into a
        %struct for axis selection
        function result = tree2struct_axes(app)
            nodes = app.Tree3.CheckedNodes;
            result = struct();
            for idx=1:length(nodes)
                node = nodes(idx);
                if ~isempty(node.NodeData)
                    NodeData = node.NodeData;
                    result.(NodeData.Rec).(NodeData.type).(node.Text) = NodeData.axis;
                end
            end
        end
        
        %% Plotting function Used for recording/acquisition type wise plotting of signals
        function [ax] = plotRaw_App(app,Rec,acquisition,rec_idx)
            
            GDP_keys = keys(app.GDP_data_dict);
            rec = app.GDP_data_dict(GDP_keys{rec_idx});
            sensor_data = Rec.(acquisition);
            stack = app.stack;
            AllSensorsNames = fieldnames(orderfields(sensor_data));

            %Removal of the 'unit' field as it holds no data
            MusclesNames = AllSensorsNames(~strcmp(AllSensorsNames,'unit'));

            %Removal of the stimulation signals as they skew signal scaling
            MusclesNames = MusclesNames(~contains(MusclesNames,'stimulation'));   
            %% Placing left muscles on the left and right on the right if stack is False
            
            if app.stack

                vals = 1:length(MusclesNames);

            else
                
                for i = 1:length(MusclesNames)
                    if MusclesNames{i}(1) == 'L'
                        val_L = 1:2:length(MusclesNames)-1;
                    else 
                        val_R = 2:2:length(MusclesNames);
                    end 
                end
                vals = [val_L val_R];

            end
            
            %% Find the best y axis scale for each sensor
            %Signals are scaled with respect to the signal with greatest
            %amplitude
            val_std = zeros(1,2);
            
            for j = 1:length(vals)
            
                max_ = max(sensor_data.(MusclesNames{j}).value); 
                min_ = min(sensor_data.(MusclesNames{j}).value);
            
                if min_ < val_std(1,1)
                    val_std(1,1) = min_;
                end
                if max_ > val_std(1,2)
                    val_std(1,2) = max_;
                end
                    
            end
            
            %%
            
            % Plot sensor data. 1 plot = 1 recording & 1 acquisition type
                fig = figure;
                %Determine figure layout according to parameters
                if app.overlap
                    [ha, pos] = tight_subplot(1,1,[.0 .03],[.1 .1],[.05 .05]);
                elseif app.stack
                    [ha, pos] = tight_subplot(length(MusclesNames),1,[.0 .03],[.1 .1],[.05 .05]);
                else
                    [ha, pos] = tight_subplot(ceil(length(MusclesNames)/2),2,[.0 .03],[.1 .1],[.05 .05]);
                end

                for i = 1:length(MusclesNames)

                    if app.overlap
                        current_axis = ha(1);
                        hold(current_axis,'on')
                    else
                        current_axis = ha(vals(i));
                    end

                    idx = i;
                    time = sensor_data.(MusclesNames{idx}).time;
                    
                    value = sensor_data.(MusclesNames{idx}).value;
                    
                    %Stimulation protocol have their own axis to avoid
                    %plotting different scales
                  
                    if app.Broadcast
                        yyaxis left
                    
                    end

                    if app.overlap
                        plot(current_axis,time,value,'DisplayName',MusclesNames{i});
                    else
                        plot(current_axis,time,value,'DisplayName','Raw');
                    end
                    
                    %Parametrization of plotAnnotatedSignal for stim, gait
                    %and envelope plotting
                    annotated_plot_params.title = MusclesNames{idx};
                    annotated_plot_params.rec_index = rec_idx;
                    annotated_plot_params.musc_index = idx;
                    annotated_plot_params.y_min = val_std(1,1);
                    annotated_plot_params.y_max = val_std(1,2);
                    
                    
                    if app.gait
                        %fast_check = 1 removes manual intervention in findGaitEvents
                        fast_check = 1;
                        gait_cycles_tbl = findGaitEvents(rec,fast_check);
                    else

                        gait_cycles_tbl = [];
                    
                    end

                    %% Plot stim using stimulation params table
                    plotAnnotatedSignal_App(app,rec,acquisition,annotated_plot_params,fig,gait_cycles_tbl,app.stim,app.gait,current_axis)
                    
                    %Processing so that only bottom plots have a Time-Axis
                    %Y-Axis is removed for every plot
                    if ~app.overlap
                        if app.stack 
                            if vals(i) < length(vals)
                                set(current_axis,'XTick',[]);
                            else
                                xlabel(current_axis,'Time [ms]');
                                xticklabels(current_axis,'auto');
                            end
                        else
                            if vals(i) < length(vals)-1
                                set(current_axis,'XTick',[]);
                            else
                                xlabel(current_axis,'Time [ms]');
                                xticklabels(current_axis,'auto')
                            end
                        end
                        ylabel(current_axis,MusclesNames{i});
                    else
                        xlabel('Time [ms]');
                        xticklabels(current_axis,'auto');
                    end

                    set(current_axis,'YTick',[]);
                    hYLabel = get(current_axis,'YLabel');
                    set(hYLabel,'rotation',60,'VerticalAlignment','middle','HorizontalAlignment','right')
                    
                    if val_std(1,1)~=val_std(1,2)
                        ylim(current_axis,[val_std(1,1) val_std(1,2)]);
                    end

                    if app.Broadcast
                       Plot_stim(app,time,current_axis)
                    end

                    xlim(current_axis,[time(1) time(end)]);
                    
                end
                
                %Legend is only showed if on of the Plotting Toggles is on
                if app.stim || app.gait || app.overlap || app.Broadcast
                        u = legendUnq;
                        legend(u);
                end
                sgtitle(['Rec' num2str(rec_idx) '-' strcat(acquisition,' [',sensor_data.unit,']')]);
            
            end
            
            %% Plotting function Used for signal comparison
            function result = plot_RAW_comp(app,Rec,acquisition,rec_idx,ha,start_idx,fig,time_vec,last_rec)
            
                GDP_keys = keys(app.GDP_data_dict);
                rec = app.GDP_data_dict(GDP_keys{rec_idx});
                sensor_data = Rec.(acquisition);
                stack = 1; %Only stacking is allowed when comparing
                AllSensorsNames = fieldnames(orderfields(sensor_data));
    
                MusclesNames = AllSensorsNames(~strcmp(AllSensorsNames,'unit'));
                MusclesNames = MusclesNames(~contains(MusclesNames,'stimulation'));   
                vals = 1:length(MusclesNames);
                
                %% Find the best y axis scale for each sensor
                %Signals are scaled with respect to the signal with greatest
                %amplitude
                val_std = zeros(1,2);
                
                for j = 1:length(vals)
                
                    max_ = max(sensor_data.(MusclesNames{j}).value); 
                    min_ = min(sensor_data.(MusclesNames{j}).value);
                
                    if min_ < val_std(1,1)
                        val_std(1,1) = min_;
                    end
                    if max_ > val_std(1,2)
                        val_std(1,2) = max_;
                    end
                        
                end
                
                %%
                
                % Plot sensor data. 1 plot = several recordings & acquisition
                % types

                for i = 1:length(MusclesNames)
                    
                    if app.overlap
                        current_axis = ha(1);
                    else
                        current_axis = ha(vals(i)+start_idx);
                    end

                    idx = i;
                    time = sensor_data.(MusclesNames{idx}).time;
                    value = sensor_data.(MusclesNames{idx}).value;
                    
                    %Stimulation protocol have their own axis to avoid
                    %plotting different scales
                    if app.Broadcast
                        yyaxis left
                    end

                    new_time = time - time(1);
                    if app.overlap
                        hold(current_axis,'on')
                        plot(current_axis,new_time,value,'DisplayName',[MusclesNames{i} '_{Rec' num2str(rec_idx) '}']);
                    else
                        plot(current_axis,new_time,value,'DisplayName','Raw');
                    end
                    
                    %Parametrization of plotAnnotatedSignal for stim, gait
                    %and envelope plotting
                    annotated_plot_params.title = MusclesNames{idx};
                    annotated_plot_params.rec_index = rec_idx;
                    annotated_plot_params.musc_index = idx;
                    annotated_plot_params.y_min = val_std(1,1);
                    annotated_plot_params.y_max = val_std(1,2);
                    
                    
                    if app.gait
                        %fast_check = 1 removes manual intervention in findGaitEvents
                        fast_check = 1;
                        gait_cycles_tbl = findGaitEvents(rec,fast_check);
                    else
                        gait_cycles_tbl = [];
                    end
            
                    %% Plot stim using stimulation params table
                    plotAnnotatedSignal_App(app,rec,acquisition,annotated_plot_params,fig,gait_cycles_tbl,app.stim,app.gait,current_axis)
                    if app.Broadcast
                        Plot_stim(app,time,current_axis)
                        yyaxis(current_axis,'right');
                        current_axis.YAxis(2).Visible='off';
                        yyaxis(current_axis,'left')
                    end
                    %Processing so that only bottom plots have a Time-Axis
                    %Y-Axis is removed for every plot
                    if vals(i) < length(vals) || ~strcmp(last_rec,strcat("Rec",num2str(rec_idx))) && ~app.overlap
                        set(current_axis,'XTick',[]);
                    else
                        xticklabels(current_axis,'auto')
                    end
                    set(current_axis,'YTick',[]);
                    xlim(current_axis,[0,time_vec(end)-time_vec(1)])
                    hYLabel = get(current_axis,'YLabel');
                    set(hYLabel,'rotation',0,'VerticalAlignment','middle','HorizontalAlignment','right')
                    if ~app.overlap
                        ylabel(current_axis,[MusclesNames{i} '_{Rec' num2str(rec_idx) '}']);
                    end

                    %legend
                    if val_std(1,1) ~= val_std(1,2)
                        ylim(current_axis,[val_std(1,1) val_std(1,2)]);
                        %xlim(current_axis,[time(1) time(end)])
                    end
                    
                end

            end
        
        
        function [ax] = plot_RAW(app,GDP_struct)
            
            Recs_names = fieldnames(GDP_struct);
            
            N_rec = length(Recs_names); % number of recordings
            
            if app.compare_session

                plot_size = 0; %Determine the amount of rows in the figure
                time_size = 0; %Determine which recording has the longest time vector
                acq_count = 0; %Determine the amount of rows in the figure
                title = ''; %Stores the number of each recording

                for rec_idx=1:N_rec
                    
                    current_rec = GDP_struct.(Recs_names{rec_idx});
                    acquisition_types = fieldnames(current_rec);
                    N_acq = length(acquisition_types);
                    acq_count = max(acq_count,N_acq);
                    
                    for acq_idx=1:N_acq

                        acquisition = acquisition_types{acq_idx};
                        plot_size = plot_size+length(fieldnames(current_rec.(acquisition))) -1 - sum(contains(fieldnames(current_rec.(acquisition)),'stimulation'));
                        sensors = fieldnames(current_rec.(acquisition));
                        sensor = sensors{1};
                        current_time_size = size(current_rec.(acquisition).(sensor));
                        time_size = max(time_size,current_time_size(1));
                        current_time = current_rec.(acquisition).(sensor).time;
                        app.compared_recs.(Recs_names{rec_idx}).time = current_time;

                        if length(current_rec.(acquisition).(sensor).time) == time_size
                            time_vec = current_rec.(acquisition).(sensor).time;
                        end
                        
                    end
                
                end

                %Determine figure layout according to parameters
                fig = figure;
                if app.overlap
                    [ha, pos] = tight_subplot(1,1,[.0 .03],[.11 .1],[.15 .01]);
                    current_axis = ha(1);
                else
                    [ha, pos] = tight_subplot(plot_size,1,[.0 .03],[.11 .1],[.15 .01]);
                end

                start_idx = 0; %index used for subplot placement when changing acquisition type/recording

                %% Recording wise plotting
                for rec_idx=1:N_rec

                    current_rec = GDP_struct.(Recs_names{rec_idx});
                    acquisition_types = fieldnames(current_rec);
                    N_acq = length(acquisition_types);
                    current_rec_idx = sscanf(Recs_names{rec_idx},"Rec%d");
                    
                    %% Acquisition type wise plotting
                    for acq_idx=1:N_acq

                        acquisition = acquisition_types{acq_idx};
                        plot_RAW_comp(app,current_rec,acquisition,current_rec_idx,ha,start_idx,fig,time_vec,Recs_names{end}); %plotting
                        start_idx = start_idx + length(fieldnames(current_rec.(acquisition))) - 1 - sum(contains(fieldnames(current_rec.(acquisition)),'stimulation')); %all data containing 'stimulation is removed

                    end

                    if ~app.overlap
                        current_axis = ha(start_idx); %Don't change axis if overlapping
                    end
                    
                    %legend
                    if (app.stim || app.gait) && app.overlap
                        u = legendUnq(current_axis);
                        legend(current_axis,u);
                    elseif app.overlap
                        u = legendUnq(current_axis);
                        legend(current_axis,u);
                    elseif app.stim || app.gait || app.Broadcast
                        u = legendUnq(current_axis);
                        legend(current_axis,u);
                    end

                    title = [title Recs_names{rec_idx} ' '];

                end
                
                xlabel('Time [ms]');

                %update Linked Axes Tree
                app.basic_tree.Children.delete;
                app.Tree3 = create_Tree_Axes(app);

                sgtitle(['Comparison ' title]);
            else

                %update Linked Axes Tree
                app.basic_tree.Children.delete;
                
                %% Recording wise plotting
                for rec_idx=1:N_rec

                    current_rec = GDP_struct.(Recs_names{rec_idx});
                    acquisition_types = fieldnames(current_rec);
                    N_acq = length(acquisition_types);
                    current_rec_idx = sscanf(Recs_names{rec_idx},"Rec%d");
                    
                    %% Acquisition type wise plotting
                    for acq_idx=1:N_acq

                        acquisition = acquisition_types{acq_idx};
                        plotRaw_App(app,current_rec,acquisition,current_rec_idx); %plotting
                        app.Tree3 = create_Tree_Axes(app);

                    end
                
                end
     
            end
            
        end


        function plotAnnotatedSignal_App(app,rec,acquisition,params,fig,gait_cycles_tbl,stim,gait,current_axis)

        %   PLOTANNOTATEDSIGNAL plots a chosen signal from the GDP dictionary,
        %   based on the variables within "params". The signal is then annotated 
        %   using the stimulation data contained in the "stimulation_params_table"
        %   and colored based on the same data.
        %   To see what the parameters are, you can move the cursor over a datapoint 
        %   and the parameter information will be displayed as text.
        %
        %   Input Arguments:
        %   App
        %   rec: Fields: ID|acquisition_type|sampling_frequency|unit|data|filter
        %   acquisition: Name of Acquisition (ex: 'DelsysEMG')
        %   params: Fields: Title (sensor name)|rec_index|musc_index|y_min|y_max
        %   rec: Data relative to the current recording
        %   fig: Current figure
        %   gait_cycles_tbl: table containing Foot Strike/Foot Off events
        %   timestamps. Empty if gait = 0
        %   stim: Stim Flag
        %   gait: Gait Flag
        %   current_axis: Current axis on which to plot
        %
        % Author: PM, MH
        
        j = params.rec_index;
        
        MuscleName = params.title;
        iRow = find(ismember([rec.acquisition_type],acquisition));
        data = rec(iRow).data.(MuscleName);
        axis tight
        stimulation_params_table = app.stim_tbl;
        %% Plot envelope

        if app.envelope && ~app.compare_session && strcmp(acquisition,'DelsysEMG')
            plot(data.time, data.envelope,'-','LineWidth',2);
        elseif app.envelope && app.compare_session && strcmp(acquisition,'DelsysEMG')
            time = data.time - data.time(1);
            plot(time, data.envelope,'-','LineWidth',2);
        end

        %% Plot gait events
        
        if gait
            hold on
            gaits_leftO = gait_cycles_tbl.Events.LFO; %Left Foot Off
            gaits_leftS = gait_cycles_tbl.Events.LFS; %Left Foot Strike
            gaits_rightO = gait_cycles_tbl.Events.RFO; %Right Foot Off
            gaits_rightS = gait_cycles_tbl.Events.RFS; %Right Foot Strike
            
            %Plotting of left swing patches
            for k1 = 1:length(gaits_leftS)
                q = [gaits_leftS(k1) gaits_leftO(k1)];
                qx = [min(q) max(q)  max(q)  min(q)];
                if app.compare_session
                    if ~app.normalize
                        qx = qx - data.time(1);
                    else
                        qx = (qx - min(data.time))/(max(data.time)-min(data.time));
                    end
                end
                yl = [params.y_min,params.y_max];
                qy = [[2 2]*yl(1) [2 2]*yl(2)];
                patch(current_axis,qx, qy,'r', 'FaceAlpha',0.2, 'EdgeColor','none','DisplayName','Left Swing');
            end
            
            %Plotting of right swing patches
            for k1 = 1:length(gaits_rightS)
                q = [gaits_rightS(k1) gaits_rightO(k1)];
                qx = [min(q) max(q)  max(q)  min(q)];
                if app.compare_session
                    if ~app.normalize
                        qx = qx - data.time(1);
                    else
                        qx = (qx - min(data.time))/(max(data.time)-min(data.time));
                    end
                end
                yl = [params.y_min,params.y_max];
                qy = [[2 2]*yl(1) [2 2]*yl(2)];
                patch(current_axis,qx, qy,'k', 'FaceAlpha',0.2, 'EdgeColor','none','DisplayName','Right Swing');
            end       
            
        end
        
        %%
        %Plot stimulations
        if stim
            %% Recognition of the different stims using Label in stimulation_params_table
            labels_on = {'On','Trigger Stim'}; 
            labels_standby = {'Standby Loop'};

            %Separation of stims depending on Label
            stim_on = stimulation_params_table(contains(string(stimulation_params_table.Label),labels_on),:);
            standby = stimulation_params_table(contains(string(stimulation_params_table.Label),labels_standby),:);
           
            
            [C,IA,IC] = unique(stim_on.timestamp_start);
            stim_on = stim_on(IA,:); %Only keep a subset of all stim_on to eliminate waveform duplicates

            [C,IA,IC] = unique(standby.timestamp_start);
            standby = standby(IA,:); %Only keep a subset of all standby to eliminate waveform duplicates
            
            %Check if current recording is between the first and last stims
            if ~isempty(stim_on)
                on_label = labels_on{find(contains(labels_on,string(stim_on.Label)))};
                stim_time = [];
                index_sep = find((stim_on.timestamp_start(2:end) - stim_on.timestamp_stop(1:end-1))>0); %Find index of off events

                time_start = stim_on.timestamp_start(1);
                time_stop = stim_on.timestamp_stop(index_sep(1));
                
                for idx=1:length(index_sep)
                    stim_time = [stim_time; data.time(time_start<data.time & time_stop>data.time)]; %Find which datapoints are beetwen on-off events
                    time_start = stim_on.timestamp_start(index_sep(idx)+1);
    
                    if idx ~= length(index_sep)
                        time_stop = stim_on.timestamp_stop(index_sep(idx+1));
                    else
                        time_stop = stim_on.timestamp_stop(end);
                    end
                end
            
                stim_time = [stim_time; data.time(time_start<data.time & time_stop>data.time)]; %
            
                if ~isempty(stim_time)
                    stim_time = unique(stim_time);
                    [tf,loc] = ismember(data.time,stim_time);
                    values = NaN(1,length(data.time),'single');
                    values(tf) = data.value(tf);
                    if app.compare_session
                        if ~app.normalize
                            hold(current_axis,'on')
                            time = data.time - data.time(1);
                            plot(current_axis,time,values,'color',[249,82,91]/255,'DisplayName',on_label)
                        else
                            time = (data.time - min(data.time))/(max(data.time)-min(data.time));
                            hold(current_axis,'on')
                            plot(current_axis,time,values,'color',[249,82,91]/255,'DisplayName',on_label)
                        end
                    else
                        hold(current_axis,'on')
                        plot(current_axis,data.time,values,'color',[249,82,91]/255,'DisplayName',on_label)
                    end
                end

            end
            
            %Check if current recording is between the first and last stims
            if ~isempty(standby)
                standby_label = labels_standby{find(contains(labels_standby,string(standby.Label)))};
                stim_time = [];
                index_sep = find((standby.timestamp_start(2:end) - standby.timestamp_stop(1:end-1))>0);

                time_start = standby.timestamp_start(1);
                time_stop = standby.timestamp_stop(index_sep(1));

                for idx=1:length(index_sep)
                    stim_time = [stim_time; data.time(time_start<data.time & time_stop>data.time)]; %Find which datapoints are beetwen on-off events
                    time_start = standby.timestamp_start(index_sep(idx)+1);
    
                    if idx ~= length(index_sep)
                        time_stop = standby.timestamp_stop(index_sep(idx+1));
                    else
                        time_stop = standby.timestamp_stop(end);
                    end
                end
            
                stim_time = [stim_time; data.time(time_start<data.time & time_stop>data.time)]; %Add correlated time points between last separation and last off event

                if ~isempty(stim_time)
                    stim_time = unique(stim_time);
                    [tf,loc] = ismember(data.time,stim_time);
                    values = NaN(1,length(data.time),'single');
                    values(tf) = data.value(tf);

                    if app.compare_session
                        if ~app.normalize
        
                            hold(current_axis,'on')
                            time = data.time - data.time(1);
                            plot(current_axis,time,values,'color',[252,176,33]/255,'DisplayName',standby_label)

                        else

                            time = (data.time - min(data.time))/(max(data.time)-min(data.time));
                            hold(current_axis,'on')
                            plot(current_axis,time,values,'color',[252,176,33]/255,'DisplayName',standby_label)

                        end
                
                    else

                        hold(current_axis,'on')
                        plot(current_axis,data.time,values,'color',[252,176,33]/255,'DisplayName',standby_label)

                    end
    
                end

            end
            
 
            %%
            %Cursor configuration to get stimulatin info in plots
            dcm_obj = datacursormode(fig);
            set(dcm_obj,'UpdateFcn',@myupdatefcn)
        end

        function txt = myupdatefcn(empt,event_obj)
        
            pos = get(event_obj,'Position'); %Position info for choosing the right timestamp
            tar = get(event_obj,'Target'); % Target info for knowing which recording is targeted in comparison mode
            if app.compare_session
                
                YLabel = tar.Parent.YLabel.String;
                split_label = split(YLabel,'Rec');
                rec_idx = sscanf(split_label{end},'%d');
                current_rec = strcat('Rec',num2str(rec_idx));
                
                if ~app.normalize
                    actual_time = app.compared_recs.(current_rec).time;
                    timestamp = pos(1)+actual_time(1);
                else
                    actual_time = app.compared_recs.(current_rec).time;
                    timestamp = pos(1)*(max(actual_time)-min(actual_time)) + min(actual_time);
                end
            else
                timestamp = pos(1);
                
            end
            
            stim_target = stimulation_params_table((stimulation_params_table.timestamp_start<timestamp)&(stimulation_params_table.timestamp_stop>timestamp),:);

            if isempty(stim_target)
                row = strcat("Time: ",num2str(timestamp),"| Signal Amp: ", num2str(pos(2)),"| No stim!");
                txt = row;
            else
                timestamp_target = stim_target.timestamp_start(1);
                index = find(stimulation_params_table.timestamp_start==timestamp_target);
                index = index(1);
                wvf_i = 0;
                txt = [];
                names = stimulation_params_table.Properties.VariableNames;
                while stimulation_params_table.timestamp_start(index + wvf_i) == timestamp_target
                    row = [];
                    stim_row = stimulation_params_table(index+wvf_i,:);
                    for i=1:length(names)
                        current_var = stim_row.(names{i});
                        if ~isempty(current_var)
                            if isa(current_var,'double') || isa(current_var,'logical')
                                current_var = num2str(current_var);
                            end
                            new_string = strcat(names{i} ,": " , current_var," | ");
                            row = strcat(row, new_string);
                            
                        end
                    
                    end
                    row = strrep(row,'_',' ');
%                     if ~(stim_row.name == "")
%                         row = strcat("Time: ",num2str(round(stim_row.timestamp_start)),"| Signal Amp: ", num2str(pos(2)), ...
%                             strcat(" | Name: ",stim_row.name), ...
%                             " | Freq: ",num2str(stim_row.frequency), ...
%                             " | Amp: ",num2str(stim_row.amplitude), ...
%                             " | PW: ",num2str(stim_row.pulse_width),...
%                             " | Stim idx: ",num2str(index + wvf_i),...
%                             " | Label: ",stim_row.Label);   
%                     else
%                     row = strcat("Time: ",num2str(round(stim_row.timestamp_start)),"| Signal Amp: ", num2str(pos(2)), ...
%                         strcat(" | ElecConfig: ",stim_row.electrode_conf_txt), ...
%                         " | Freq: ",num2str(stim_row.frequency), ...
%                         " | Amp: ",num2str(stim_row.amplitude), ...
%                         " | PW: ",num2str(stim_row.pulse_width),...
%                         " | Stim idx: ",num2str(index + wvf_i),...
%                         " | Label: ",stim_row.Label);
%                     end
                    txt{wvf_i+1} = row;
                    wvf_i = wvf_i + 1;
               end
            end
        end

        end
        
        function [ax] = plot_kinematic_parameters_App(app,GDP_data_dict,params)
            % plot_kinematic_parameters plots the kinematic parameters of
            % recordings from GDP_data_dict. The recordings to extract from
            % are selected using params. The following parameters are
            % plotted:
            % Stride time (SDT) 
            % Swing time (SWT) 
            % Swing to Stride ratio (SWtoSD) 
            % Peak Angular Velocity of Shank (pTB) 
            % Peak Angular Velocity of Thigh (pFE) 
            % Angle Range of Shank (rTB) 
            % Angle Range of Thigh (rFE)
            %
            % inputs:
            % GDP_data_dict: key= folder name, value = array of data (struct with 
            %            fields: ID, Acquisition_Type, Data (struct with fields: 
            %            time, variables recorded))
            % params: subsets_idx = [index of recordings to be plotted
            %            separately], compare = [index of recordings to be plotted
            %            together]
           

            GDP_keys = keys(GDP_data_dict);
            
            fast_check = 1; %Flag for quick extraction of gait parameters in findGaitEvents
            
            %% Plot parameters for Recs separately
            if ~isempty(params.subset_idx)
                rec_idx = params.subset_idx;
                N_rec = length(rec_idx); % number of recordings
                rec1 = GDP_data_dict(GDP_keys{rec_idx(1)}); % Use one recording to find desired row
                iRow = find(ismember([rec1.acquisition_type],'DelsysIMU')); % row of Delsys IMU
                
                for i=1:N_rec
                    fig = figure;
                    idx = rec_idx(i);
                    current_rec = GDP_data_dict(GDP_keys{idx});
                    
                    gait_cycles_tbl = findGaitEvents(current_rec,fast_check);
                    IMU_data = current_rec(iRow).data;
                    FS = current_rec(iRow).sampling_frequency;
                    %%
                    %Left & Right extraction
                    [params_left,params_right] = getKinematicsData(gait_cycles_tbl,IMU_data,FS);
                    fields = fieldnames(params_right);
            
                    X = categorical({'Left','Right'});

                    for j=1:length(fields)

                        subplot(2,4,j);
                        Y = [params_left.(fields{j})(1) params_right.(fields{j})(1)]; %Plotting of first component: mean of the kinematic parameter
                        Error = [params_left.(fields{j})(2) params_right.(fields{j})(2)]/2; %Use of second component for error plotting: std of the kinematic parameter
                        bar(X,Y);
            
                        hold on
            
                        Err = errorbar(X,Y,Error);    
                        Err.Color = [0 0 0];                            
                        Err.LineStyle = 'none';  
                        
                        hold off
                        title(fields{j})

                    end
                    sgtitle(['Kinematic Parameters Rec' num2str(idx)]);
                end
            end

            %% Plot kinematic parameters for all Recs for comparison
            if ~isempty(params.compare_idx)

                fig = figure;
                rec_idx_comp = params.compare_idx;
                plot_title = '';
                N_rec = length(rec_idx_comp); % number of recordings
                rec1 = GDP_data_dict(GDP_keys{rec_idx_comp(1)});
                iRow = find(ismember([rec1.acquisition_type],'DelsysIMU')); % row of Delsys EMG
                kinematic_params = cell(length(rec_idx_comp),2);
                plot_label = cell(N_rec,1);

                for idx=1:length(rec_idx_comp)

                    plot_title = [plot_title 'Rec' num2str(rec_idx_comp(idx)) ' '];
                    plot_label{idx} = strcat('Rec',num2str(rec_idx_comp(idx)));
                    current_rec = GDP_data_dict(GDP_keys{rec_idx_comp(idx)});
                    gait_cycles_tbl = findGaitEvents(current_rec,fast_check);
                    IMU_data = current_rec(iRow).data;
                    FS = current_rec(iRow).sampling_frequency;
                    [left,right] = getKinematicsData(gait_cycles_tbl,IMU_data,FS);
                    kinematic_params{idx,1} = left;
                    kinematic_params{idx,2} = right;


                end

                fields = fieldnames(kinematic_params{1,2});
                X = categorical({'Left','Right'});

                for j=1:length(fields)

                    subplot(2,4,j);
                    
                    Y = [];
                    Error = [];
                    for idx=1:length(rec_idx_comp)
                        Y = [Y;kinematic_params{idx,1}.(fields{j})(1) kinematic_params{idx,2}.(fields{j})(1)];
                        Error = [Error;kinematic_params{idx,1}.(fields{j})(2) kinematic_params{idx,2}.(fields{j})(2)];
                    end
                   
                    b = bar(X,Y);
                    title(fields{j}) 
                    
                    hold on
        
                    [ngroups,nbars] = size(Y);
                    x = nan(ngroups, nbars);
                    for i = 1:ngroups
                        x(i,:) = b(i).XEndPoints;
                    end

                    % Plot the errorbars
                    errorbar(x,Y,Error,'k','linestyle','none');                   

                    hold off
         
                end

                hLg=legend(plot_label,'Location','southeast');
                sgtitle(['Kinematic Parameter Comparison ' plot_title]);

            end
        
        end
    end
    
    methods (Access = public)
        
        function [ax] = Plot_stim(app,data_time,axis)

            %Plots the amplitude through time of the selected stimulation
            %protocol
            stim_name = app.StimulationsListBox.Value;
            stimulation_params_table = app.stim_tbl;
            selection = stimulation_params_table(strcmp(stimulation_params_table.name,stim_name),:);

            if any((data_time >= selection.timestamp_start(1) & data_time <= election.timestamp_start(end)))

                time_start = selection.timestamp_start(2:end);
                time_stop = selection.timestamp_stop(1:end-1);
                time_diff = time_start-time_stop;
                jump_idx = find(time_diff>0);
                jump_idx = [jump_idx length(time_stop)];
                N_jump = length(jump_idx);
                start_idx = 1;
                end_idx = jump_idx(1);

                X=[];
                Y=[];

                for idx=1:N_jump-1  

                    if start_idx == 0

                        start_idx = jump_idx(idx)+1;
                        end_idx = jump_idx(idx+1);
                        continue

                    end

                    x = sort([selection.timestamp_start(start_idx:end_idx);selection.timestamp_stop(start_idx:end_idx)]);
                    x = [x(1); x ;x(end)];
                    X = [X;x];
                    y = repelem(selection.amplitude(start_idx:end_idx),2);
                    if length(y) == 2
                        y = y.';
                    end
                    y = [0;y;0];
                    Y = [Y;y];
                    start_idx = jump_idx(idx)+1;
                    end_idx = jump_idx(idx+1);
                end

                new_X = X(X>=data_time(1)& X<=data_time(end));
                new_Y = Y(X>=data_time(1)& X<=data_time(end));

                if app.compare_session
                   new_X = new_X - data_time(1);
                end
                
                
                if ~isempty(new_X)

                    hold(axis,"on")
                    yyaxis(axis,"right")
                    plot(axis,new_X,new_Y,'color','black','DisplayName',stim_name);
                    set(axis,'YTick',[]);
                    ylim(axis,[-max(Y),max(Y)])
                    yyaxis(axis,"left")
                end
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, GDP_data_dict, stim_tbl)
            app.GDP_data_dict = GDP_data_dict;

            app.stim_tbl = stim_tbl;
            app.stack = 1;
            app.stim = 0;                                                                                                                                                                              
            app.gait = 0;
            app.Tree = create_Tree(app,app.Tree);
            app.Tree2 = create_Tree_2(app,app.Tree2);
            app.StackCheckBox.Value = 1;
            app.basic_tree = app.Tree3;
            app.normalize = 0;
            app.overlap = 0;
            app.envelope = 0;
            app.compare_session = 0;
            app.Broadcast = 0;
            if ~isempty(stim_tbl)
                app.StimulationsListBox.Items = cellstr(unique(stim_tbl.name)).';
            else
                app.StimulationsListBox.Items = string();
            end

        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
             close all;
             app.Tree3.Children.delete;
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            t_subset = app.t(round((changingValue/100)*length(app.t)):end);
            plot(app.UIAxes,t_subset,sin(t_subset))
        end

        % Callback function
        function ExportDataPushed(app, event)


        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            %app.UITable.Data = selectedNodes;

        end

        % Callback function
        function ExportstructButtonPushed(app, event)
            res = tree2struct_axes(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Rec_struct = tree2struct(app);
            plot_RAW(app,Rec_struct);
        end

        % Value changed function: StimSwitch
        function StimSwitchValueChanged(app, event)
            value = app.StimSwitch.Value;
            if strcmp(value,'On')
                app.stim = 1;
            else
                app.stim = 0;
            end
        end

        % Value changed function: GaitSwitch
        function GaitSwitchValueChanged(app, event)
            value = app.GaitSwitch.Value;
            if strcmp(value,'On')
                app.gait = 1;
            else
                app.gait = 0;
            end
        end

        % Value changed function: StackCheckBox
        function StackCheckBoxValueChanged(app, event)
            value = app.StackCheckBox.Value;
            app.stack = value;
        end

        % Value changed function: CompareCheckBox
        function CompareCheckBoxValueChanged(app, event)
            value = app.CompareCheckBox.Value;
            app.compare_session = value;
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            Nodes = app.Tree2.CheckedNodes;
            selected_node = app.Tree2.SelectedNodes;

            if strcmp(selected_node.Text,'All Recs')
                rec_size = size(app.GDP_data_dict);
                subset_idx = 1:1:rec_size(1);
            else
                rec_size = size(Nodes);
                subset_idx = zeros(1,rec_size(1));
                for i=1:rec_size(1)
                    current_id = sscanf(Nodes(i).Text,'Rec%d');
                    subset_idx(i) = current_id;
                end
            end

            if app.CompareCheckBox_2.Value
                params.compare_idx = subset_idx;
                params.subset_idx = {};
            else
                params.subset_idx = subset_idx;
                params.compare_idx = {};
            end
            plot_kinematic_parameters_App(app,app.GDP_data_dict,params)
        end

        % Callback function: Tree3
        function Tree3CheckedNodesChanged(app, event)
            checkedNodes = app.Tree3.CheckedNodes;
            
            linked_axes = [];
            unlinked_axes = [];
            all_children = app.Tree3.Children.Children;
            
            if app.compare_session
                for idx=1:length(all_children)
                    unlinked_axes = [unlinked_axes all_children(idx).NodeData];
                end
    
                if ~isempty(unlinked_axes)
                    linkaxes(unlinked_axes,'off')
                end
    
                for idx=1:length(checkedNodes)
                    linked_axes = [linked_axes checkedNodes(idx).NodeData];
                end
    
                if ~isempty(linked_axes)
                    linkaxes(linked_axes,'x')
                end
            else
                tree_struct = tree2struct_axes(app);
                recs = fieldnames(tree_struct);
                unlinked_axes = [];
                all_recs = app.Tree3.Children;
                N_recs = length(all_recs);
                for rec_idx=1:N_recs
                    acq = all_recs(rec_idx).Children;
                    N_acq = length(acq);
                    for acq_idx=1:N_acq
                        axes = acq(acq_idx).Children;
                        N_axes = length(axes);
                        for axis_idx=1:N_axes
                            unlinked_axes = [unlinked_axes axes(axis_idx).NodeData.axis];
                        end
                    end
                end
                
                if ~isempty(unlinked_axes)
                    linkaxes(unlinked_axes,'off')
                end

                if ~isempty(recs)
                    for rec_idx=1:length(recs)
                        current_rec = recs{rec_idx};
                        acquisition_types = fieldnames(tree_struct.(current_rec));
                        for acq_idx=1:length(acquisition_types)
                            linked_axes = [];
                            current_acq = acquisition_types{acq_idx};
                            axes = fieldnames(tree_struct.(current_rec).(current_acq));
                            for ax_idx=1:length(axes)
                                current_ax = axes{ax_idx};
                                linked_axes = [linked_axes tree_struct.(current_rec).(current_acq).(current_ax)];
                            end
                            if ~isempty(linked_axes)
                                linkaxes(linked_axes,'x')
                            end
                        end
                    end
                end
            end
        end

        % Value changed function: StimulationsListBox
        function StimulationsListBoxValueChanged(app, event)
            value = app.StimulationsListBox.Value;
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)

            stim_name = app.StimulationsListBox.Value;
            stimulation_params_table = app.stim_tbl;
            selection = stimulation_params_table(strcmp(stimulation_params_table.name,stim_name),:);
            time_start = selection.timestamp_start(2:end);
            time_stop = selection.timestamp_stop(1:end-1);
            time_diff = time_start-time_stop;
            jump_idx = find(time_diff>0);
            jump_idx = [jump_idx length(time_stop)];
            N_jump = length(jump_idx);
            start_idx = 1;
            end_idx = jump_idx(1);
            fig=figure;
            X=[];
            Y=[];
                for idx=1:N_jump
    
                    if start_idx == 0
                        start_idx = jump_idx(idx)+1;
                        end_idx = jump_idx(idx+1);
                        continue
                    end
                       % x = sort([selection.timestamp_start(start_idx:end_idx);selection.timestamp_stop(start_idx:end_idx)]);
                    x = sort([selection.timestamp_start(start_idx:end_idx);selection.timestamp_stop(start_idx:end_idx)]);
                    x = [x(1); x ;x(end)];
                    X = [X;x];
                    y = repelem(selection.amplitude(start_idx:end_idx),2);
                    if length(y) == 2
                        y = y.';
                    end
                    y = [0;y;0];
                    Y = [Y;y];
                    start_idx = jump_idx(idx)+1;
                    if idx == N_jump
                        end_idx = selection.timestamp_stop(end);
                    else
                    end_idx = jump_idx(idx+1);
                    end
                end

            plot(X,Y);
            ylabel('Amplitude')
            xlabel('Time [ms]');
            sgtitle(['Stimulation ' stim_name]);
        end

        % Button pushed function: SaveAllFiguresButton
        function SaveAllFiguresButtonPushed(app, event)
            FolderName = app.SaveFileEditField.Value;   % Your destination folder
            FigList = findobj(allchild(0), 'flat', 'Type', 'figure');
            if ~(isfolder(FolderName))
                   mkdir(FolderName)
            end
            for iFig = 1:length(FigList)
                if ~isempty(FigList(iFig).Number)
                  FigHandle = FigList(iFig);
                  FigName = FigHandle.Children(1).String;
                  FigName(FigName==' ') = '_';
                  %FigName   = get(name, 'String');
                  savefig(FigHandle, fullfile(FolderName, [FigName '.fig']));
                end
            end
        end

        % Value changed function: OverlapCheckBox
        function OverlapCheckBoxValueChanged(app, event)
            value = app.OverlapCheckBox.Value;
            app.overlap = value;
        end

        % Value changed function: EnvelopeSwitch
        function EnvelopeSwitchValueChanged(app, event)
            value = app.EnvelopeSwitch.Value;
            if strcmp(value,'On')
                app.envelope = 1;
            else
                app.envelope = 0;
            end
        end

        % Value changed function: BroadcastCheckBox
        function BroadcastCheckBoxValueChanged(app, event)
            value = app.BroadcastCheckBox.Value;
            app.Broadcast = value;
        end

        % Callback function: Tree, Tree
        function TreeClicked(app, event)
            node = event.InteractionInformation.Node;
            if class(node.Parent) == "matlab.ui.container.CheckBoxTree"
                for i=1:length(node.Children)
                    child = node.Children(i); % is a type node
                    if isempty(child.Children) %check if children were already created
                        AllSensors = fieldnames(child.NodeData.data);
                        for j=1:length(AllSensors) % create all child treenodes
                            node_data.Rec = node.Text;
                            node_data.type = child.Text;
                            node_data.data = child.NodeData.data.(AllSensors{j});
                            node_data.unit = child.NodeData.unit;
                            sensor_node = uitreenode(child,"Text",AllSensors{j},"NodeData",node_data);
                        end
                        %remove node data to save memory
                        child.NodeData = [];
                    end
                end                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotStimulationProtocolsPanel = uipanel(app.UIFigure);
            app.PlotStimulationProtocolsPanel.Title = 'Plot Stimulation Protocols';
            app.StimulationsListBoxLabel = uilabel(app.PlotStimulationProtocolsPanel);
            app.StimulationsListBoxLabel.Text = 'Stimulations';
            app.StimulationsListBox = uilistbox(app.PlotStimulationProtocolsPanel);
            app.StimulationsListBox.ValueChangedFcn = createCallbackFcn(app, @StimulationsListBoxValueChanged, true);
            app.PlotButton_3 = uibutton(app.PlotStimulationProtocolsPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.BroadcastCheckBox = uicheckbox(app.PlotStimulationProtocolsPanel);
            app.BroadcastCheckBox.ValueChangedFcn = createCallbackFcn(app, @BroadcastCheckBoxValueChanged, true);
            app.BroadcastCheckBox.Text = 'Broadcast';
            app.PlotKinematicParametersPanel = uipanel(app.UIFigure);
            app.PlotKinematicParametersPanel.Title = 'Plot Kinematic Parameters';
            app.Tree2 = uitree(app.PlotKinematicParametersPanel, 'checkbox');
            app.PlotButton_2 = uibutton(app.PlotKinematicParametersPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.CompareCheckBox_2 = uicheckbox(app.PlotKinematicParametersPanel);
            app.CompareCheckBox_2.Text = 'Compare';
            app.PlotSessionDataPanel = uipanel(app.UIFigure);
            app.PlotSessionDataPanel.Title = 'Plot Session Data';
            app.StimSwitchLabel = uilabel(app.PlotSessionDataPanel);
            app.StimSwitchLabel.Text = 'Stim';
            app.StimSwitch = uiswitch(app.PlotSessionDataPanel, 'slider');
            app.StimSwitch.ValueChangedFcn = createCallbackFcn(app, @StimSwitchValueChanged, true);
            app.GaitSwitchLabel = uilabel(app.PlotSessionDataPanel);
            app.GaitSwitchLabel.Text = 'Gait';
            app.GaitSwitch = uiswitch(app.PlotSessionDataPanel, 'slider');
            app.GaitSwitch.ValueChangedFcn = createCallbackFcn(app, @GaitSwitchValueChanged, true);
            app.PlotButton = uibutton(app.PlotSessionDataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Tree = uitree(app.PlotSessionDataPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            app.Tree.DoubleClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.StackCheckBox = uicheckbox(app.PlotSessionDataPanel);
            app.StackCheckBox.ValueChangedFcn = createCallbackFcn(app, @StackCheckBoxValueChanged, true);
            app.StackCheckBox.Text = 'Stack';
            app.CompareCheckBox = uicheckbox(app.PlotSessionDataPanel);
            app.CompareCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompareCheckBoxValueChanged, true);
            app.CompareCheckBox.Text = 'Compare';
            app.LinkedAxesPanel = uipanel(app.PlotSessionDataPanel);
            app.LinkedAxesPanel.Title = 'Linked Axes';
            app.Tree3 = uitree(app.LinkedAxesPanel, 'checkbox');
            % Assign Checked Nodes
            app.Tree3.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree3CheckedNodesChanged, true);
            app.OverlapCheckBox = uicheckbox(app.PlotSessionDataPanel);
            app.OverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapCheckBoxValueChanged, true);
            app.OverlapCheckBox.Text = 'Overlap';
            app.EnvelopeSwitchLabel = uilabel(app.PlotSessionDataPanel);
            app.EnvelopeSwitchLabel.Text = 'Envelope';
            app.EnvelopeSwitch = uiswitch(app.PlotSessionDataPanel, 'slider');
            app.EnvelopeSwitch.ValueChangedFcn = createCallbackFcn(app, @EnvelopeSwitchValueChanged, true);
            app.SaveClosePanel = uipanel(app.UIFigure);
            app.SaveClosePanel.Title = 'Save & Close';
            app.CloseallButton = uibutton(app.SaveClosePanel, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.SaveFileLabel = uilabel(app.SaveClosePanel);
            app.SaveFileLabel.Text = 'Save File:';
            app.SaveFileEditField = uieditfield(app.SaveClosePanel, 'text');
            app.SaveFileEditField.Value = 'C:\Users\Admin\Documents\file_prova.png';
            app.SaveAllFiguresButton = uibutton(app.SaveClosePanel, 'push');
            app.SaveAllFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllFiguresButtonPushed, true);
            app.SaveAllFiguresButton.Text = 'Save All Figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualisationTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end